classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StateDropDownLabel          matlab.ui.control.Label
        StateDropDown               matlab.ui.control.DropDown
        CountyDropDownLabel         matlab.ui.control.Label
        CountyDropDown              matlab.ui.control.DropDown
        CityDropDownLabel           matlab.ui.control.Label
        CityDropDown                matlab.ui.control.DropDown
        UIAxes                      matlab.ui.control.UIAxes
        PollutantinfoDropDownLabel  matlab.ui.control.Label
        PollutantinfoDropDown       matlab.ui.control.DropDown
        UIAxes_2                    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          states=evalin('base','addone');
          app.StateDropDown.Items= states;
          app.PollutantinfoDropDown.Items={'Select One','CO','NO2','O3','SO2'};
          app.UIFigure.Color='[1, 1, .9]';
          app.UIAxes.XTick = [0:.1:10];
        end

        % Value changed function: StateDropDown
        function StateDropDownValueChanged(app, event)

            state=app.StateDropDown.Value;
            state = string(state);
            assignin('base','stateinput',state); %assigns  state in Appdesigner to 'stateinput' in matlab%
            
            if strcmp(state,'Massachusetts')
                app.CountyDropDown.Items= {'Select One','Suffolk'}
            end
            if strcmp(state,'California')
                app.CountyDropDown.Items= {'Select One','Contra Costa','Imperial','Kern','Los Angeles','Orange','Riverside','Sacramento','San Bernardino',...
                    'San Diego','San Francisco','Santa Barbara',...
                    'Santa Cruz','Solvano','Ventura','Alameda','Humboldt'}
                   
            end
            if strcmp(state,'Arizona')
                app.CountyDropDown.Items={'Select One', 'Maricopa','Pima'}
            end
            if strcmp(state,'Colorado')
                app.CountyDropDown.Items={'Select One','Adams','Denver'}
            end
            if strcmp(state,'Connecticut')
                app.CountyDropDown.Items={'Select One','Hartford','New Haven','Fairfield'}
            end
            
            if strcmp(state,'District of Columbia')
                app.CountyDropDown.Items={'Select One','District of Columbia'}
            end
            if strcmp(state,'Florida')
                app.CountyDropDown.Items={'Select One','Orange'}
            end
            if strcmp(state,'Illinois')
                app.CountyDropDown.Items={'Select One','Cook','Saint Clair'}
            end
            if strcmp(state,'Indiana')
                app.CountyDropDown.Items={'Select One','Marion'}
            end
            if strcmp(state,'Kansas')
                app.CountyDropDown.Items={'Select One','Linn','Summer','Wyandotte'}
            end
            if strcmp(state,'Kentucky')
                app.CountyDropDown.Items={'Select One','Boyd','Daviess','Fayette','Henderson','Jefferson','McCracken',}
            end
            if strcmp(state,'Louisiana')
                app.CountyDropDown.Items={'Select One','East Baton Rouge'}
            end
            if strcmp(state,'Michigan')
                app.CountyDropDown.Items={'Select One','Wayne','Kent'}
            end
            if strcmp(state,'Missouri')
                app.CountyDropDown.Items={'Select One','Saint Louis','St. Louis City',}
            end
            if strcmp(state,'New Jersey')
                app.CountyDropDown.Items={'Select One','Camden','Essex'}
            end
            if strcmp(state,'New York')
                app.CountyDropDown.Items={'Select One','Bronx','Queens','Suffolk'}
            end
            if strcmp(state,'North Carolina')
                app.CountyDropDown.Item={'Select One','Forsyth','Mecklenburg'}
            end
            if strcmp(state,'South Carolina')
                app.CountyDropDown.Items={'Select One','Charleston'}
            end
            if strcmp(state,'Tennesse')
                app.CountyDropDown.Items={'Select One','Haywood','Meigs'}
            end
            if strcmp(state,'Oklahoma')
                app.CountyDropDown.Items={'Select One','Adair','Cherokee','Kay','Ottawa','Tulsa'}
            end
            if strcmp(state,'Pennsylvania')
                app.CountyDropDown.Item={'Select One','Allegheny','Beaver','Berks','Blair','Bucks','Cambria','Erie','Lackawanna','Lancaster','Lawrence','Luzerne','Montgomery','Northampton','Philadelphia','Washington','Westmoreland','York'}
            end
            if strcmp(state,'Texas')
                app.CountyDropDown.Items={'Select One','Dallas','El Paso','Harris','McLennan'}
            end
            if strcmp(state,'Virginia')
                app.CountyDropDown.Items={'Select One','Fairfax','Alexandria City'}
            end
            if strcmp(state,'Nevada')
                app.CountyDropDown.Items={'Select One','Clark'}
            end
            if strcmp(state,'New Hampshire')
                app.CountyDropDown.Items={'Select One','Hillsborough'}
            end
            if strcmp(state,'Maine')
                app.CountyDropDown.Items={'Select One','Aroostook'}
            end
            if strcmp(state,'Maryland')
                app.CountyDropDown.Items={'Select One','Baltimore'}
            end
            if strcmp(state,'Wisconsin')
                app.CountyDropDown.Items={'Select One','Milwaukee'}
            end
            if strcmp(state,'Iowa')
                app.CountyDropDown.Items={'Select One','Polk','Scott'}
            end
            if strcmp(state,'Oregon')
                app.CountyDropDown.Items={'Select One', 'Multnomah'}
            end
            if strcmp(state,'Wyoming')
                app.CountyDropDown.Items={'Select One','Uinta'}
            end
            if strcmp(state,'North Dakota')
                app.CountyDropDown.Items={'Select One','Cass'}
            end
            if strcmp(state,'Ohio')
                app.CountyDropDown.Items={'Select One','Athens','Medina'}
            end
            if strcmp(state,'Georgia')
                app.CountyDropDown.Items={'Select One','DeKalb'}
            end
            if strcmp(state,'Idaho')
                app.CountyDropDown.Items={'Select One','Ada'}
            end
            
            %minus SC,Country of Mex,Wyoming?,Idaho,Georgia,ND%
        end

        % Value changed function: CountyDropDown
        function CountyDropDownValueChanged(app, event)

            county=app.CountyDropDown.Value;
            county = string(county);
            
            assignin('base','countyinput',county); %assigns  county in Appdesigner to 'countyinput' in matlab%
            
            if strcmp(county,'Athens')
                app.CityDropDown.Items={'Select One','Athens'}
            end
            if strcmp(county,'Adair')
                app.CityDropDown.Items={'Select One','Cherry Tree'}
            end
            if strcmp(county,'Humboldt')
                app.CityDropDown.Items={'Select One','Eureka'}
            end
            
            if strcmp(county,'McLennan')
                app.CityDropDown.Items={'Select One','Waco'}
            end
            if strcmp(county,'Fairfax')
                app.CityDropDown.Items={'Select One','Annadale','McLean'}
            end
            
            if strcmp(county,'Multnomah')
                app.CityDropDown.Items={'Select One','Portland'}
            end
            if strcmp(county,'Polk')
                app.CityDropDown.Items={'Select One','Des Moines'}
            end
            if strcmp(county,'Scott')
                app.CityDropDown.Items={'Select One','Davenport'}
            end
            
            if strcmp(county,'Hartford')
                app.CityDropDown.Items={'Select One','East Hartford'}
            end
            if strcmp(county,'New Haven')
                app.CityDropDown.Items={'Select One','New Haven'}
            end
            if strcmp(county,'Fiarfield')
                app.CityDropDown.Items={'Select One','Westport'}
            end
            if strcmp(county,'Milwaukee')
                app.CityDropDown.Items={'Select One','Milwaukee'}
            end
            
            if strcmp(county,'Luzerne')
                app.CityDropDown.Items={'Select One','Wilkese-Barre'}
            end
            
            if strcmp(county,'Erie')
                app.CityDropDown.Items={'Select One','Erie'}
            end
            
            if strcmp(county,'Tulsa')
                app.CityDropDown.Items={'Select One','Tulsa'}
            end
            
            if strcmp(county,'Baltimore')
                app.CityDropDown.Items={'Select One','Essex'}
            end
            
            if strcmp(county,'Aroostook')
                app.CityDropDown.Items={'Select One','Presque Isle'}
            end
            if strcmp(county,'Hillsborough')
                app.CityDropDown.Items={'Select One','Manchester'}
            end
            if strcmp(county,'Denver')
                app.CityDropDown.Items={'Select One','Denver'}
            end
                    
            if strcmp(county,'Kent')
                app.CityDropDown.Items={'Select One','Grand Rapids'}
            end
            
            if strcmp(county,'Alameda')
                app.CityDropDown.Items={'Select One','Oakland'}
            end
            if strcmp(county,'Ottawa')
                app.CityDropDown.Items={'Select One','Miami'}
            end
            
            if strcmp(county,'Suffolk')
                app.CityDropDown.Items={'Select One','Boston'}
            end
            if strcmp(county,'Maricopa')
                app.CityDropDown.Items={'Select One','Phoenix','Scottsdale'}
            end
            if strcmp(county,'Pima')
                app.CityDropDown.Items={'Select One','Tucson'}
            end
            if strcmp(county,'Contra Costa')
                app.CityDropDown.Items={'Select One','Concord','Bethel Island',...
                    'San Pablo','Pittsburg','Crockett'}
            end
            if strcmp(county,'Imperial')
                app.CityDropDown.Items={'Select One','Calexico'}
            end
            if strcmp(county,'Kern')
                app.CityDropDown.Items={'Select One','Bakersfield'}
            end
            if strcmp(county,'Los Angeles')
                app.CityDropDown.Items={'Select One','Burbank','Los Angeles','Long Beach','Hawthorne','West Los Angeles'}
            end
            if strcmp(county,'Orange')
                app.CityDropDown.Items={'Select One','Costa Mesa'}
            end
            if strcmp(county,'Riverside')
                app.CityDropDown.Items={'Select One','Rubidoux'}
            end
            if strcmp(county,'Sacramento')
                app.CityDropDown.Items={'Select One','Arden-Arcade'}
            end
            if strcmp(county,'San Bernardino')
                app.CityDropDown.Items={'Select One','Victorville'}
            end
            if strcmp(county,'San Diego')
                app.CityDropDown.Items={'Select One','Chula Vista','San Diego'}
            end
            if strcmp(county,'San Francisco')
                app.CityDropDown.Items={'Select One','San Francisco'}
            end
            if strcmp(county,'Santa Barbara')
                app.CityDropDown.Items={'Select One','Capitan','Lompoc','Goleta','Vandenburh Air Force Base'}
            end
            if strcmp(county,'Santa Cruz')
                app.CityDropDown.Items={'Select One','Davenport'}
            end
            if strcmp(county,'Solano')
                app.CityDropDown.Items={'Select One','Vallejo'}
            end
            if strcmp(county,'Adams')
                app.CityDropDown.Items={'Select One','Welby'}
            end
            if strcmp(county,'District of Columbia')
                app.CityDropDown.Items={'Select One','Washington'}
            end
            if strcmp(county,'Orange')
                app.CityDropDown.Items={'Select One','Winter Park'}
            end
            if strcmp(county,'Cook')
                app.CityDropDown.Item={'Select One','Chicago','Cicero','Calumet City (PU RR name Calumet Park (sta.))'}
            end
            if strcmp(county,'Saint Clair')
                app.CityDropDown.Items={'Select One','East Saint Louis'}
            end
            if strcmp(county,'Marion')
                app.CityDropDown.Items={'Select One','Indianapolis'}
            end
            if strcmp(county,'Wyandotte')
                app.CityDropDown.Items={'Select One','Kansas City'}
            end
            if strcmp(county,'Boyd')
                app.CityDropDown.Items={'Select One','Ashland'}
            end
            if strcmp(county,'Fayette')
                app.CityDropDown.Items={'Select One','Lexington-Fayette (corporate name for Lexington)'}
            end
            if strcmp(county,'Henderson')
                app.CityDropDown.Items={'Select One','Henderson'}
            end
            if strcmp(county,'Jefferson')
                app.CityDropDown.Items={'Select One','Louisville'}
            end
            if strcmp(county,'McCracken')
                app.CityDropDown.Items={'Select One','Paducah'}
            end
            if strcmp(county,'East Baton Rouge')
                app.CityDropDown.Items={'Select One','Baton Rouge'}
            end
            if strcmp(county,'Wayne')
                app.CityDropDown.Items={'Select One','Detroit'}
            end
            if strcmp(county,'Saint Louis')
                app.CityDropDown.Items={'Select One','Sunset Hill','Ladue','Ferguson','St. Ann','Breckenridge Hills'}
            end
            if strcmp(county,'St. Louis City')
                app.CityDropDown.Items={'Select One','St. Louis'}
            end
            if strcmp(county,'Camden')
                app.CityDropDown.Items={'Select One','Camden'}
            end
            if strcmp(county,'Essex')
                app.CityDropDown.Items={'Select One','Newark'}
            end
            
            if strcmp(county,'Bronx')
                app.CityDropDown.Items={'Select One','New York'}
            end
            if strcmp(county,'Queens')
                app.CityDropDown.Items={'Select One','New York'}
            end
            if strcmp(county,'Suffolk')
                app.CityDropDown.Items={'Select One','Holtsville','Boston'}
            end
            if strcmp(county,'Forsyth')
                app.CityDropDown.Items={'Select One','Winston-Salem'}
            end
            if strcmp(county,'Mecklenburg')
                app.CityDropDown.Items={'Select One','Charlotte'}
            end
            if strcmp(county,'Cherokee')
                app.CityDropDown.Items={'Select One','Park Hill'}
            end
            if strcmp(county,'Kay')
                app.CityDropDown.Items={'Select One','Ponca City'}
            end
            if strcmp(county,'Allegheny')
                app.CityDropDown.Items={'Select One','Pittsburgh'}
            end
            if strcmp(county,'Beaver')
                app.CityDropDown.Items={'Select One','Beaver Falls'}
            end
            if strcmp(county,'Berks')
                app.CityDropDown.Items={'Select One','Reading'}
            end
            if strcmp(county,'Blair')
                app.CityDropDown.Items={'Select One','Altoona'}
            end
            if strcmp(county,'Bucks')
                app.CityDropDown.Items={'Select One','Bristol'}
            end
            if strcmp(county,'Cambria')
                app.CityDropDown.Items={'Select One','Johnstown'}
            end
            if strcmp(county,'Lackawanna')
                app.CityDropDown.Items={'Select One','Scranton'}
            end
            if strcmp(county,'Lancaster')
                app.CityDropDown.Items={'Select One','Lancaster'}
            end
            if strcmp(county,'Lawrence')
                app.CityDropDown.Items={'Select One','New Castle'}
            end
            if strcmp(county,'Montgomery')
                app.CityDropDown.Items={'Select One','Norristown'}
            end
            if strcmp(county,'Northampton')
                app.CityDropDown.Items={'Select One','Freemansburg'}
            end
            if strcmp(county,'Philadelphia')
                app.CityDropDown.Items={'Select One','Philadelphia'}
            end
            if strcmp(county,'Washington')
                app.CityDropDown.Items={'Select One','Charleroi'}
            end
            if strcmp(county,'Westmoreland')
                app.CityDropDown.Items={'Select One','Greensburg'}
            end
            if strcmp(county,'York')
                app.CityDropDown.Items={'Select One','York'}
            end
            if strcmp(county,'Dallas')
                app.CityDropDown.Items={'Select One','Dallas'}
            end
            if strcmp(county,'El Paso')
                app.CityDropDown.Items={'Select One','El Paso'}
            end
            if strcmp(county,'Harris')
                app.CityDropDown.Items={'Select One','Houston'}
            end
            if strcmp(county,'Fairfax')
                app.CityDropDown.Items={'Select One','Seven Corners','McLean'}
            end
            if strcmp(county,'Alexandria City')
                app.CityDropDown.Items={'Select One','Alexandria'}
            end
            if strcmp(county,'Clark')
                app.CityDropDown.Items={'Select One','Boulder City'}
            end
        end

        % Value changed function: CityDropDown
        function CityDropDownValueChanged(app, event)
            city = app.CityDropDown.Value;
            city = string(city);
            assignin('base','cityinput',city); %assigns city in Appdesigner to 'cityinput' in matlab%
            
        end

        % Value changed function: PollutantinfoDropDown
        function PollutantinfoDropDownValueChanged(app, event)
            pollutype = app.PollutantinfoDropDown.Value;
            pollutype = string(pollutype);
            
            assignin('base','pollutypeinput',pollutype); %assigns  pullutype in Appdesigner to 'pollutypeinput' in matlab%
            pollutype = char(pollutype);
            x=evalin('base','x');
            y=evalin('base','y');
            z=evalin('base','z');
            app.UIAxes.Title.String = 'Pollution Emissions Over Time';
            app.UIAxes.XLabel.String = 'Time in Days Since 1/1/2000';
            app.UIAxes_2.XLabel.String = 'Time in Days Since 1/1/2000';
            app.UIAxes_2.YLabel.String = 'AQI';
            app.UIAxes_2.Title.String = 'AQI Measurements Over Time';
            if strcmp(pollutype,'NO2')
                plot(app.UIAxes,x,y)
                plot(app.UIAxes_2,x,z)
                app.UIAxes.YLabel.String = 'Amount of NO2 Emissions (ppb)';
                
                
            end
            if strcmp(pollutype,'O3')
                plot(app.UIAxes,x,y)
                plot(app.UIAxes_2,x,z)
                app.UIAxes.YLabel.String = 'Amount of O3 Emissions (ppm)';
            end
            if strcmp(pollutype,'SO2')
                plot(app.UIAxes,x,y)
                plot(app.UIAxes_2,x,z)
                app.UIAxes.YLabel.String = 'Amount of SO2 Emissions (ppb)';
            end
            if strcmp(pollutype,'CO')
                plot(app.UIAxes,x,y)
                plot(app.UIAxes_2,x,z)
                app.UIAxes.YLabel.String = 'Amount of CO Emissions (ppm)';
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StateDropDownLabel = uilabel(app.UIFigure);
            app.StateDropDownLabel.Text = 'State';
            app.StateDropDown = uidropdown(app.UIFigure);
            app.StateDropDown.Items = {};
            app.StateDropDown.ValueChangedFcn = createCallbackFcn(app, @StateDropDownValueChanged, true);
            app.StateDropDown.Value = {};
            app.CountyDropDownLabel = uilabel(app.UIFigure);
            app.CountyDropDownLabel.Text = 'County';
            app.CountyDropDown = uidropdown(app.UIFigure);
            app.CountyDropDown.Items = {'', '', '', ''};
            app.CountyDropDown.ValueChangedFcn = createCallbackFcn(app, @CountyDropDownValueChanged, true);
            app.CountyDropDown.Value = '';
            app.CityDropDownLabel = uilabel(app.UIFigure);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.UIFigure);
            app.CityDropDown.Items = {'', '', '', ''};
            app.CityDropDown.ValueChangedFcn = createCallbackFcn(app, @CityDropDownValueChanged, true);
            app.CityDropDown.Value = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 2 4 6 8 10];
            app.PollutantinfoDropDownLabel = uilabel(app.UIFigure);
            app.PollutantinfoDropDownLabel.Text = 'Pollutant info';
            app.PollutantinfoDropDown = uidropdown(app.UIFigure);
            app.PollutantinfoDropDown.Items = {'', '', '', ''};
            app.PollutantinfoDropDown.ValueChangedFcn = createCallbackFcn(app, @PollutantinfoDropDownValueChanged, true);
            app.PollutantinfoDropDown.Value = '';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.XTick = [0 2 4 6 8 10];
        end
    end

    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end